classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GainMarginEditField        matlab.ui.control.NumericEditField
        GainMarginEditFieldLabel   matlab.ui.control.Label
        PhaseMarginEditField       matlab.ui.control.NumericEditField
        PhaseMarginEditFieldLabel  matlab.ui.control.Label
        ParametersPanel            matlab.ui.container.Panel
        HoldLabel                  matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        EditField_3                matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.NumericEditField
        EditField                  matlab.ui.control.NumericEditField
        TFLabel                    matlab.ui.control.Label
        PlotButton                 matlab.ui.control.Button
        EditField_5                matlab.ui.control.EditField
        Label                      matlab.ui.control.Label
        EditField_4                matlab.ui.control.EditField
        DampingRatioSlider         matlab.ui.control.Slider
        DampingRatioLabel          matlab.ui.control.Label
        GainKSlider                matlab.ui.control.Slider
        GainKSliderLabel           matlab.ui.control.Label
        NaturalFrequencySlider     matlab.ui.control.Slider
        NaturalFrequencyLabel      matlab.ui.control.Label
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            %app.EditField.Value = app.NaturalFrequencySliderValueChanging.changingValue;
            %value = app.EditField.Value;
            app.NaturalFrequencySlider.Value = app.EditField.Value; 
        end

        % Value changed function: NaturalFrequencySlider
        function NaturalFrequencySliderValueChanged(app, event)
            %value = app.NaturalFrequencySliderValueChanged.Value;
            app.EditField.Value = app.NaturalFrequencySlider.Value; 
        end

        % Value changing function: NaturalFrequencySlider
        function NaturalFrequencySliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value = changingValue;
            %app.PhaseMarginEditField.Value = changingValue;
            %Global W;
            %W = changingValue;
        end

        % Callback function
        function EditField_2ValueChanging(app, event)
            changingValue = app.NaturalFrequencySliderValueChanging.changingValue;
            app.EditField_2.Value = changingValue;
        end

        % Value changing function: GainKSlider
        function GainKSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_2.Value = changingValue;
            %Global k;
            %k = changingValue;
        end

        % Value changing function: DampingRatioSlider
        function DampingRatioSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_3.Value = changingValue;
            %Global d;
            %d = changingValue;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            k = app.GainKSlider.Value;
            W = app.NaturalFrequencySlider.Value;
            d = app.DampingRatioSlider.Value;
            G = tf([k*W*W],[1 2*W*d W*W]);
            p = pole(G);
            z = zero(G);
            
            [mag,phase,wout] = bode(G);
            % Calculate Pm, Gm:
            [Gm,Pm,Wcg,Wcp] = margin(G);
            mag = squeeze(mag);
            mag = 20 * log10(mag);
            wout = log10(wout);
            plot(app.UIAxes,step(G), 'LineWidth', 1.5);
            semilogx(app.UIAxes2,wout,mag,'LineWidth',1.5);
            semilogx(app.UIAxes4,wout,squeeze(phase), 'LineWidth', 1.5);

            %hold(app.UIAxes3, 'on');
            plot(app.UIAxes3, real(p), imag(p), 'x', 'LineWidth', 1.5); % Plot poles


            %plot(app.UIAxes3,pzmap(G))
            %plot(app.UIAxes3, real(z), imag(z), 'o', 'Color', 'blue'); % Plot zeros
            %hold(app.UIAxes3, 'off');

            kstr = num2str(k*W*W);
            d1str = num2str(2*W*d);
            d2str = num2str(W*W);
            %num = [kstr
            app.EditField_4.Value = [kstr];
            app.EditField_5.Value = ['s² + ' d1str 's + ' d2str];
            app.GainMarginEditField.Value = Gm;
            app.PhaseMarginEditField.Value = Pm;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmpi(value, 'On')
                hold(app.UIAxes, 'on');
                hold(app.UIAxes2, 'on');
                hold(app.UIAxes3, 'on');
                hold(app.UIAxes4, 'on');
            else
                hold(app.UIAxes, 'off');
                hold(app.UIAxes2, 'off');
                hold(app.UIAxes3, 'off');
                hold(app.UIAxes4, 'off');
            end

        end

        % Value changing function: EditField_4
        function EditField_4ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_4.BorderStyle = 'none';
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.EditField_5.BorderStyle = 'none';
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.GainKSlider.Value = app.EditField_2.Value;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.DampingRatioSlider.Value = app.EditField_3.Value;
        end

        % Value changed function: GainMarginEditField
        function GainMarginEditFieldValueChanged(app, event)
            value = app.GainMarginEditField.Value;
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'StepResponse')
            xlabel(app.UIAxes, 'time (seconds)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'BodePlot (Magnitude)')
            xlabel(app.UIAxes2, 'Frequency (log base 10)')
            ylabel(app.UIAxes2, 'Magnitude(dB)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'PoleZero')
            xlabel(app.UIAxes3, 'Real (σ)')
            ylabel(app.UIAxes3, 'Imaginary (jΩ)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Bode (Phase)')
            xlabel(app.UIAxes4, 'Frequency (log base 10)')
            ylabel(app.UIAxes4, 'Phase(deg)')
            zlabel(app.UIAxes4, 'Z')
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.NaturalFrequencyLabel = uilabel(app.ParametersPanel);
            app.NaturalFrequencyLabel.Text = {'Natural Frequency'; '(ωₙ)'};
            app.NaturalFrequencySlider = uislider(app.ParametersPanel);
            app.NaturalFrequencySlider.Limits = [0 1000];
            app.NaturalFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @NaturalFrequencySliderValueChanged, true);
            app.NaturalFrequencySlider.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencySliderValueChanging, true);
            app.GainKSliderLabel = uilabel(app.ParametersPanel);
            app.GainKSliderLabel.Text = 'Gain (K)';
            app.GainKSlider = uislider(app.ParametersPanel);
            app.GainKSlider.ValueChangingFcn = createCallbackFcn(app, @GainKSliderValueChanging, true);
            app.DampingRatioLabel = uilabel(app.ParametersPanel);
            app.DampingRatioLabel.Text = {'Damping Ratio'; '(δ)'};
            app.DampingRatioSlider = uislider(app.ParametersPanel);
            app.DampingRatioSlider.Limits = [0 1];
            app.DampingRatioSlider.ValueChangingFcn = createCallbackFcn(app, @DampingRatioSliderValueChanging, true);
            app.EditField_4 = uieditfield(app.ParametersPanel, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.ValueChangingFcn = createCallbackFcn(app, @EditField_4ValueChanging, true);
            app.Label = uilabel(app.ParametersPanel);
            app.Label.Text = '━━━━━━━━━━━━';
            app.EditField_5 = uieditfield(app.ParametersPanel, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.PlotButton = uibutton(app.ParametersPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TFLabel = uilabel(app.ParametersPanel);
            app.TFLabel.Text = 'TF =';
            app.EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.ParametersPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.ParametersPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.Switch = uiswitch(app.ParametersPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.HoldLabel = uilabel(app.ParametersPanel);
            app.HoldLabel.Text = 'Hold';
            app.PhaseMarginEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseMarginEditFieldLabel.Text = 'Phase Margin';
            app.PhaseMarginEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainMarginEditFieldLabel = uilabel(app.UIFigure);
            app.GainMarginEditFieldLabel.Text = 'Gain Margin';
            app.GainMarginEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainMarginEditField.ValueChangedFcn = createCallbackFcn(app, @GainMarginEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end